classdef DATCOM_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ControlCardsTab                 matlab.ui.container.Tab
        DimensionsUnitButtonGroup       matlab.ui.container.ButtonGroup
        ImperialButton                  matlab.ui.control.RadioButton
        MetricButton                    matlab.ui.control.RadioButton
        DerivativesUnitButtonGroup      matlab.ui.container.ButtonGroup
        DegreeButton                    matlab.ui.control.RadioButton
        RadianButton                    matlab.ui.control.RadioButton
        OtherOptionsPanel               matlab.ui.container.Panel
        DynamicDerivativesadvicemakeitonCheckBox  matlab.ui.control.CheckBox
        PartmoduleCheckBox              matlab.ui.control.CheckBox
        BuildmoduleCheckBox             matlab.ui.control.CheckBox
        SaveControlCardsDataButton      matlab.ui.control.Button
        FlightConditionsTab             matlab.ui.container.Tab
        TakeoffWeightEditFieldLabel     matlab.ui.control.Label
        TakeoffWeightEditField          matlab.ui.control.NumericEditField
        OptionsTab                      matlab.ui.container.Tab
        ReferenceAreaEditFieldLabel     matlab.ui.control.Label
        ReferenceAreaEditField          matlab.ui.control.NumericEditField
        MeanAerodynamcChordEditFieldLabel  matlab.ui.control.Label
        MeanAerodynamcChordEditField    matlab.ui.control.NumericEditField
        WingSpanEditFieldLabel          matlab.ui.control.Label
        WingSpanEditField               matlab.ui.control.NumericEditField
        SurfaceRoughnessEditFieldLabel  matlab.ui.control.Label
        SurfaceRoughnessEditField       matlab.ui.control.NumericEditField
        SaveOptionsDataButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveControlCardsDataButton
        function SaveControlCardsDataButtonPushed(app, event)
            ControlCardsData = {};
            if app.DimensionsUnitButtonGroup.SelectedObject.Text == "Imperial"
                ControlCardsData{1} = 'FT';
            else
                ControlCardsData{1} = 'M';
            end
            if app.DimensionsUnitButtonGroup.SelectedObject.Text == "Degree"
                ControlCardsData{2} = 'DEG';
            else
                ControlCardsData{2} = 'RAD';
            end
            if app.DynamicDerivativesadvicemakeitonCheckBox.Value == 1
                ControlCardsData{3} = 'DAMP';
            else
                ControlCardsData{3} = 'Null';
            end
            if app.PartmoduleCheckBox.Value == 1
                ControlCardsData{4} = 'PART';
            else
                ControlCardsData{4} = 'Null';
            end
            if app.BuildmoduleCheckBox.Value == 1
                ControlCardsData{5} = 'BUILD';
            else
                ControlCardsData{5} = 'Null';
            end
            writer(ControlCardsData, 'ControlCards');
        end

        % Button pushed function: SaveOptionsDataButton
        function SaveOptionsDataButtonPushed(app, event)
            OptionsData = {};
            OptionsData{1} = app.ReferenceAreaEditField.Value;
            OptionsData{2} = app.MeanAerodynamcChordEditField.Value;
            OptionsData{3} = app.WingSpanEditField.Value;
            OptionsData{4} = app.SurfaceRoughnessEditField.Value;
            writer(OptionsData, 'Options');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlCardsTab = uitab(app.TabGroup);
            app.ControlCardsTab.Title = 'Control Cards';
            app.DimensionsUnitButtonGroup = uibuttongroup(app.ControlCardsTab);
            app.DimensionsUnitButtonGroup.Title = 'Dimensions Unit';
            app.ImperialButton = uiradiobutton(app.DimensionsUnitButtonGroup);
            app.ImperialButton.Text = 'Imperial';
            app.ImperialButton.Value = true;
            app.MetricButton = uiradiobutton(app.DimensionsUnitButtonGroup);
            app.MetricButton.Text = 'Metric';
            app.DerivativesUnitButtonGroup = uibuttongroup(app.ControlCardsTab);
            app.DerivativesUnitButtonGroup.Title = 'Derivatives Unit';
            app.DegreeButton = uiradiobutton(app.DerivativesUnitButtonGroup);
            app.DegreeButton.Text = 'Degree';
            app.DegreeButton.Value = true;
            app.RadianButton = uiradiobutton(app.DerivativesUnitButtonGroup);
            app.RadianButton.Text = 'Radian';
            app.OtherOptionsPanel = uipanel(app.ControlCardsTab);
            app.OtherOptionsPanel.Title = 'Other Options';
            app.DynamicDerivativesadvicemakeitonCheckBox = uicheckbox(app.OtherOptionsPanel);
            app.DynamicDerivativesadvicemakeitonCheckBox.Text = 'Dynamic Derivatives(advice: make it on)';
            app.PartmoduleCheckBox = uicheckbox(app.OtherOptionsPanel);
            app.PartmoduleCheckBox.Text = 'Part module';
            app.BuildmoduleCheckBox = uicheckbox(app.OtherOptionsPanel);
            app.BuildmoduleCheckBox.Text = 'Build module';
            app.SaveControlCardsDataButton = uibutton(app.ControlCardsTab, 'push');
            app.SaveControlCardsDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveControlCardsDataButtonPushed, true);
            app.SaveControlCardsDataButton.Text = 'Save Control Cards Data';
            app.FlightConditionsTab = uitab(app.TabGroup);
            app.FlightConditionsTab.Title = 'Flight Conditions';
            app.TakeoffWeightEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.TakeoffWeightEditFieldLabel.Text = 'Take-off Weight';
            app.TakeoffWeightEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.ReferenceAreaEditFieldLabel = uilabel(app.OptionsTab);
            app.ReferenceAreaEditFieldLabel.Text = 'Reference Area';
            app.ReferenceAreaEditField = uieditfield(app.OptionsTab, 'numeric');
            app.MeanAerodynamcChordEditFieldLabel = uilabel(app.OptionsTab);
            app.MeanAerodynamcChordEditFieldLabel.Text = 'Mean Aerodynamc Chord';
            app.MeanAerodynamcChordEditField = uieditfield(app.OptionsTab, 'numeric');
            app.WingSpanEditFieldLabel = uilabel(app.OptionsTab);
            app.WingSpanEditFieldLabel.Text = 'Wing Span';
            app.WingSpanEditField = uieditfield(app.OptionsTab, 'numeric');
            app.SurfaceRoughnessEditFieldLabel = uilabel(app.OptionsTab);
            app.SurfaceRoughnessEditFieldLabel.Text = 'Surface Roughness';
            app.SurfaceRoughnessEditField = uieditfield(app.OptionsTab, 'numeric');
            app.SaveOptionsDataButton = uibutton(app.OptionsTab, 'push');
            app.SaveOptionsDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOptionsDataButtonPushed, true);
            app.SaveOptionsDataButton.Text = 'Save Options Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DATCOM_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end